classdef App03_UIAxes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        PlotButton           matlab.ui.control.Button
        AEditField           matlab.ui.control.NumericEditField
        AEditFieldLabel      matlab.ui.control.Label
        wradsEditField       matlab.ui.control.NumericEditField
        wradsEditFieldLabel  matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        %Compute the maximum x value so we display 3 full oscillations
        function [xMax] = getXMax(app)
            w = app.wradsEditField.Value;
            f = w/(2*pi);
            T = 1/f;

            numOscillations = 3;

            xMax = T*numOscillations;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AEditField.Value        = 2.5;
            app.wradsEditField.Value    = 0.5;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            A = app.AEditField.Value;
            w = app.wradsEditField.Value;

            t = linspace(0,app.getXMax(),300);
            ySin = A*sin(w*t);
            yCos = A*cos(w*t);

            %Plot the signals
            %Note: need to specify app.UIAxes.  If you don't use this this will generate a new plot in a separate window
            hold(app.UIAxes,'on');
            plot(app.UIAxes,t,ySin,'DisplayName','A*sin(w*t)')
            plot(app.UIAxes,t,yCos,'DisplayName','A*cos(w*t)')

            legend(app.UIAxes,'Location','Best')
            xlabel(app.UIAxes,'t (sec)');
            ylabel(app.UIAxes,'y')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'sin and cos')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.wradsEditFieldLabel = uilabel(app.UIFigure);
            app.wradsEditFieldLabel.Text = 'w (rad/s)';
            app.wradsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App03_UIAxes

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end